classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        TextArea        matlab.ui.control.TextArea
        Button          matlab.ui.control.Button
        EditField       matlab.ui.control.EditField
        Switch          matlab.ui.control.Switch
        SwitchLabel     matlab.ui.control.Label
        LogSwitch       matlab.ui.control.Switch
        LogSwitchLabel  matlab.ui.control.Label
        Button_2        matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        RMSELabel       matlab.ui.control.Label
        UIAxes_volt     matlab.ui.control.UIAxes
        UIAxes_OCV      matlab.ui.control.UIAxes
        UIAxes_R1       matlab.ui.control.UIAxes
        UIAxes_R0       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
methods (Access = public)
    function showResult(app, message)
        app.RMSELabel.Text = message;
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.csv;*.xlsx', '数据文件 (*.txt, *.csv, *.xlsx)'}, ...
                                     '选择输入文件');
            
            if isequal(file, 0) || isequal(path, 0)
                app.EditField.Value = '未选择文件';
                return;
            end
            
    
            fullpath = fullfile(path, file);
            app.EditField.Value = fullpath;
            
        
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
                     guiRun(app);
        end

        % Value changed function: LogSwitch
        function LogSwitchValueChanged(app, event)
            value = app.LogSwitch.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {728, 728};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开始';
            app.LogSwitchLabel = uilabel(app.LeftPanel);
            app.LogSwitchLabel.Text = 'Log';
            app.LogSwitch = uiswitch(app.LeftPanel, 'slider');
            app.LogSwitch.ValueChangedFcn = createCallbackFcn(app, @LogSwitchValueChanged, true);
            app.SwitchLabel = uilabel(app.LeftPanel);
            app.SwitchLabel.Text = '跳变检测';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'上升沿', '下降沿'};
            app.Switch.Value = '上升沿';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择文件';
            app.TextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_R0 = uiaxes(app.GridLayout2);
            title(app.UIAxes_R0, 'R0')
            xlabel(app.UIAxes_R0, 'SOC')
            ylabel(app.UIAxes_R0, 'Ω')
            zlabel(app.UIAxes_R0, 'Z')
            app.UIAxes_R1 = uiaxes(app.GridLayout2);
            title(app.UIAxes_R1, 'R1')
            xlabel(app.UIAxes_R1, 'SOC')
            ylabel(app.UIAxes_R1, 'Ω')
            zlabel(app.UIAxes_R1, 'Z')
            app.UIAxes_OCV = uiaxes(app.GridLayout2);
            title(app.UIAxes_OCV, 'OCV')
            xlabel(app.UIAxes_OCV, 'SOC')
            ylabel(app.UIAxes_OCV, 'Y')
            zlabel(app.UIAxes_OCV, 'Z')
            app.UIAxes_volt = uiaxes(app.GridLayout2);
            title(app.UIAxes_volt, '实验电压vs预测电压')
            xlabel(app.UIAxes_volt, 'X')
            ylabel(app.UIAxes_volt, 'Y')
            zlabel(app.UIAxes_volt, 'Z')
            app.RMSELabel = uilabel(app.GridLayout2);
            app.RMSELabel.Text = '全局电压预测 RMSE: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end